classdef simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        KitesimulatorUIFigure          matlab.ui.Figure
        KitesparametersLabel           matlab.ui.control.Label
        MasskgEditFieldLabel           matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.NumericEditField
        TetherlengthmEditFieldLabel    matlab.ui.control.Label
        TetherlengthmEditField         matlab.ui.control.NumericEditField
        InitialconditionsLabel         matlab.ui.control.Label
        ThetaEditFieldLabel            matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        PhiEditFieldLabel              matlab.ui.control.Label
        PhiEditField                   matlab.ui.control.NumericEditField
        PsiEditFieldLabel              matlab.ui.control.Label
        PsiEditField                   matlab.ui.control.NumericEditField
        Omega_thetasEditFieldLabel     matlab.ui.control.Label
        Omega_thetasEditField          matlab.ui.control.NumericEditField
        Omega_phisEditFieldLabel       matlab.ui.control.Label
        Omega_phisEditField            matlab.ui.control.NumericEditField
        SimulationparametersLabel      matlab.ui.control.Label
        LengthsEditFieldLabel          matlab.ui.control.Label
        LengthsEditField               matlab.ui.control.NumericEditField
        RelativewindkmhEditFieldLabel  matlab.ui.control.Label
        RelativewindkmhEditField       matlab.ui.control.NumericEditField
        RunsimulationButton            matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            theta_0 = app.ThetaEditField.Value*pi/180;
            phi_0 = app.PhiEditField.Value*pi/180;
            psi_0 = app.PsiEditField.Value*pi/180;
            dtheta_0 = app.Omega_thetasEditField.Value*pi/180;
            dphi_0 = app.Omega_phisEditField.Value*pi/180;
            assignin('base','x0',[phi_0; theta_0; dphi_0; dtheta_0; psi_0])
            assignin('base','t_sim',app.LengthsEditField.Value)
            assignin('base','r',app.TetherlengthmEditField.Value)
            assignin('base','m',app.MasskgEditField.Value)
            assignin('base','V_WR',app.RelativewindkmhEditField.Value/3.6)
            evalin('base','Init_sim')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.KitesimulatorUIFigure = uifigure;
            app.KitesimulatorUIFigure.Name = 'Kite simulator';
            app.KitesparametersLabel = uilabel(app.KitesimulatorUIFigure);
            app.KitesparametersLabel.Text = 'Kite''s parameters';
            app.MasskgEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass (kg)';
            app.MasskgEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.MasskgEditField.Limits = [0 100];
            app.MasskgEditField.Value = 4;
            app.TetherlengthmEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.TetherlengthmEditFieldLabel.Text = 'Tether length (m)';
            app.TetherlengthmEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.TetherlengthmEditField.Value = 50;
            app.InitialconditionsLabel = uilabel(app.KitesimulatorUIFigure);
            app.InitialconditionsLabel.Text = 'Initial conditions';
            app.ThetaEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.ThetaEditFieldLabel.Text = 'Theta (°)';
            app.ThetaEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.ThetaEditField.Value = 60;
            app.PhiEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.PhiEditFieldLabel.Text = 'Phi (°)';
            app.PhiEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.PsiEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.PsiEditFieldLabel.Text = 'Psi  (°)';
            app.PsiEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.Omega_thetasEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.Omega_thetasEditFieldLabel.Text = 'Omega_theta (°/s)';
            app.Omega_thetasEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.Omega_phisEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.Omega_phisEditFieldLabel.Text = 'Omega_phi (°/s)';
            app.Omega_phisEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.SimulationparametersLabel = uilabel(app.KitesimulatorUIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.LengthsEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.LengthsEditFieldLabel.Text = 'Length (s)';
            app.LengthsEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.LengthsEditField.Value = 20;
            app.RelativewindkmhEditFieldLabel = uilabel(app.KitesimulatorUIFigure);
            app.RelativewindkmhEditFieldLabel.Text = 'Relative wind (km/h)';
            app.RelativewindkmhEditField = uieditfield(app.KitesimulatorUIFigure, 'numeric');
            app.RelativewindkmhEditField.Value = 30;
            app.RunsimulationButton = uibutton(app.KitesimulatorUIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = simulator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.KitesimulatorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.KitesimulatorUIFigure)
        end
    end
end