classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AdditionalplotsCheckBox         matlab.ui.control.CheckBox
        DifferentialcontrolsignalLabel  matlab.ui.control.Label
        KitetrajectoryinflightwindowLabel  matlab.ui.control.Label
        ControllerparametersPanel       matlab.ui.container.Panel
        ConstantControlInput            matlab.ui.control.NumericEditField
        ConstantControlInputEditFieldLabel  matlab.ui.control.Label
        ControlsignalsaturationEditField  matlab.ui.control.NumericEditField
        ControlsignalsaturationEditFieldLabel  matlab.ui.control.Label
        d0mEditField                    matlab.ui.control.NumericEditField
        d0mEditFieldLabel               matlab.ui.control.Label
        ProportionalgainEditField       matlab.ui.control.NumericEditField
        ProportionalgainEditFieldLabel  matlab.ui.control.Label
        SimulationparametersPanel       matlab.ui.container.Panel
        SimulationlengthsEditField      matlab.ui.control.NumericEditField
        SimulationlengthsEditFieldLabel  matlab.ui.control.Label
        RelativewindspeedktsEditField   matlab.ui.control.NumericEditField
        RelativewindspeedktsEditFieldLabel  matlab.ui.control.Label
        TrajectoryparametersPanel       matlab.ui.container.Panel
        AzimuthEditField                matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel           matlab.ui.control.Label
        lvationEditField                matlab.ui.control.NumericEditField
        ThetacenterLabel                matlab.ui.control.Label
        RotationEditField               matlab.ui.control.NumericEditField
        RotationEditFieldLabel          matlab.ui.control.Label
        widthmEditField                 matlab.ui.control.NumericEditField
        widthmEditFieldLabel            matlab.ui.control.Label
        nEditField                      matlab.ui.control.NumericEditField
        nEditFieldLabel                 matlab.ui.control.Label
        InitialconditionsPanel          matlab.ui.container.Panel
        psi0EditField                   matlab.ui.control.NumericEditField
        psi0EditFieldLabel              matlab.ui.control.Label
        dphi0sEditField                 matlab.ui.control.NumericEditField
        dphi0sLabel                     matlab.ui.control.Label
        dtheta0sEditField               matlab.ui.control.NumericEditField
        dtheta0sLabel                   matlab.ui.control.Label
        phi0EditField                   matlab.ui.control.NumericEditField
        phi0EditFieldLabel              matlab.ui.control.Label
        theta0EditField                 matlab.ui.control.NumericEditField
        theta0EditFieldLabel            matlab.ui.control.Label
        SimulationparametersLabel       matlab.ui.control.Label
        RunsimulationButton             matlab.ui.control.Button
        control_signal                  matlab.ui.control.UIAxes
        flight_window                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
        end

        % Button pushed function: RunsimulationButton
        function RunsimulationButtonPushed(app, event)
            close all
            cla(app.flight_window)
            cla(app.control_signal)
            grid(app.control_signal,"on")
            xlabel(app.control_signal,'Time (s)')
            ylabel(app.control_signal,'$\delta$ (m)', "Interpreter","latex")
            title(app.control_signal,"")
            title(app.flight_window,"")
            axis(app.flight_window,'off')

            % Initial conditions
            theta_0 = app.theta0EditField.Value *pi/180;
            phi_0 = app.phi0EditField.Value*pi/180;
            dtheta_0 = app.dtheta0sEditField.Value*pi/180;
            dphi_0 = app.dphi0sEditField.Value*pi/180;
            psi_0 = app.psi0EditField.Value*pi/180;
            x0 = [theta_0; phi_0; dtheta_0; dphi_0; psi_0];
            assignin("base","x0",x0)

            % Trajectory
            s = app.nEditField.Value; % nombre de points
            a = app.widthmEditField.Value; % Largeur de la trajectoire
            % rotation de la trajectoire autour de l'axe des lignes
            Omega_0 = app.RotationEditField.Value * pi / 180;
            % Centre de la trajectoire, coordonnées polaires
            theta_T0 = app.lvationEditField.Value * pi / 180; 
            phi_T0 = app.AzimuthEditField.Value * pi / 180;
            paramsTrajectoire = [s, a, Omega_0, theta_T0, phi_T0];
            Trajectoire = get_trajectory(theta_T0, phi_T0, s, a, Omega_0);
            % Simulation parameters
            V_WR = app.RelativewindspeedktsEditField.Value/1.944;     % m/s
            t_sim = app.SimulationlengthsEditField.Value;               % s
            r = 50;
            
            assignin("base","paramsTrajectoire",paramsTrajectoire)
            assignin("base","V_WR",V_WR)
            assignin("base","t_sim",t_sim)
            assignin("base","r",r)

            % Controller parameters
            gain = app.ProportionalgainEditField.Value;
            epsilon = 0;
            d0 = app.d0mEditField.Value;            %m;
            controlSaturation = app.ControlsignalsaturationEditField.Value;            %m;
            constantControlInput = app.ConstantControlInput.Value;            %m;

            assignin("base","gain",gain)
            assignin("base","epsilon", epsilon)
            assignin("base","d0",d0)
            assignin("base","controlSaturation",controlSaturation)
            assignin("base","constantControlInput",constantControlInput)


            simIn = Simulink.SimulationInput("pilote_automatique");
            simIn = setModelParameter(simIn,"TimeOut",5);
            out = sim(simIn);

            app.flight_window.Visible = "on";

            x = out.x.Data;
            u = out.u.Data;
            time = out.tout(:);
            theta = x(:,1);
            phi = x(:,2);
            dot_theta = x(:,3);
            dot_phi = x(:,4);
            psi = x(:,5);
            error = out.error.Data;
            p_kite = r*[(cos(phi).*cos(theta)).'; (sin(phi).*cos(theta)).';-sin(theta).'].';
            
            % Plot flight windows
            quiver3(app.flight_window,0,0,0,r,0,0,'b','HandleVisibility','off')
            hold(app.flight_window,"on")
            quiver3(app.flight_window,0,0,0,0,r,0,'r','HandleVisibility','off')
            text(app.flight_window,r,0,0,"x")
            text(app.flight_window,0,r,0,"y")

            t=linspace(0,pi,20);
            arc = [zeros(length(t),1) cos(t).' -sin(t).'];
            My = @(alpha) [cos(alpha) 0 sin(alpha); 0 1 0; -sin(alpha) 0 cos(alpha)];
            for theta = linspace(0,-pi/2,10)
                arc2 = My(theta)*arc.'*r;
                arc2 = arc2.';
                plot3(app.flight_window,arc2(:,1), arc2(:,2), arc2(:,3),':k','HandleVisibility','off')
            end
            
            t=linspace(0,pi/2,20);
            arc = [zeros(length(t),1) cos(t).' -sin(t).'];
            Mz = @(alpha) [cos(alpha) -sin(alpha) 0; sin(alpha) cos(alpha) 0; 0 0 1];
            for theta = linspace(0,-pi,19)
                arc2 = Mz(theta)*arc.'*r;
                arc2 = arc2.';
                plot3(app.flight_window,arc2(:,1), arc2(:,2), arc2(:,3),':k','HandleVisibility','off')
            end

            app.flight_window.XDir ="Reverse";
            app.flight_window.ZDir ="Reverse";
            
            for index = 1:length(Trajectoire)
                plot3(app.flight_window,Trajectoire(index).position(1), Trajectoire(index).position(2), Trajectoire(index).position(3), 'b.')
                text(app.flight_window,Trajectoire(index).position(1)+2, Trajectoire(index).position(2)+2, Trajectoire(index).position(3)+2,num2str(index))
            end

            % Plot kite trajectory
            plot3(app.flight_window,p_kite(:,1),p_kite(:,2),p_kite(:,3),'m','LineWidth',1,'DisplayName',"Kite's trajectory");
            view(app.flight_window,[-90 30])

            dxdt=diff(p_kite(:,1))./diff(time);
            dydt=diff(p_kite(:,2))./diff(time);
            dzdt=diff(p_kite(:,3))./diff(time);
            
            v = sqrt(dxdt.^2 + dydt.^2 + dzdt.^2);
            v=[0; v];

            col = colorbar(app.flight_window);
            colormap(app.flight_window,jet)
            col.Label.String = "Kite speed (m/s)";
            col.FontSize = 12;

            clim(app.flight_window,[min(v), max(v)]);
            c = linspace(min(v), max(v), numel(p_kite(:,1)));
            surface(app.flight_window,[p_kite(:,1).';p_kite(:,1).'],[p_kite(:,2).';p_kite(:,2).'],[p_kite(:,3).';p_kite(:,3).'],[v.';v.'],'facecol','no','edgecol','interp','linew',2)

            
            % Plot control signal
            plot(app.control_signal, time, u(:,1))

            if(app.AdditionalplotsCheckBox.Value)
                theta = x(:,1);
                phi = x(:,2);
                dot_theta = x(:,3);
                dot_phi = x(:,4);
                psi = mod(x(:,5), 2*pi);
                figure
                hold on
                plot(time, theta*180/pi)
                plot(time, phi*180/pi)
                plot(time, psi*180/pi)
                xlabel('Time (s)')
                ylabel("Angle (°)")
                grid on
                legend("Élévation", "Azimuth", "Lacet")
                title("Kite state")
            end


        end

        % Callback function
        function Settings1ButtonPushed(app, event)
            app.RotationEditField.Value = -10;
            app.lvationEditField.Value = 30;
            app.AzimuthEditField.Value = 40;
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            RunsimulationButtonPushed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.flight_window = uiaxes(app.UIFigure);
            title(app.flight_window, 'Title')
            xlabel(app.flight_window, 'X')
            ylabel(app.flight_window, 'Y')
            zlabel(app.flight_window, 'Z')
            app.control_signal = uiaxes(app.UIFigure);
            title(app.control_signal, 'Title')
            xlabel(app.control_signal, 'X')
            ylabel(app.control_signal, 'Y')
            zlabel(app.control_signal, 'Z')
            app.RunsimulationButton = uibutton(app.UIFigure, 'push');
            app.RunsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton.Text = 'Run simulation';
            app.SimulationparametersLabel = uilabel(app.UIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.InitialconditionsPanel = uipanel(app.UIFigure);
            app.InitialconditionsPanel.Title = 'Initial conditions';
            app.theta0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.theta0EditFieldLabel.Text = 'theta0 (°)';
            app.theta0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.theta0EditField.Value = 75;
            app.phi0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.phi0EditFieldLabel.Text = 'phi0 (°)';
            app.phi0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.dtheta0sLabel = uilabel(app.InitialconditionsPanel);
            app.dtheta0sLabel.Text = 'dtheta0 (°/s)';
            app.dtheta0sEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.dtheta0sEditField.Value = 10;
            app.dphi0sLabel = uilabel(app.InitialconditionsPanel);
            app.dphi0sLabel.Text = 'dphi0 (°/s)';
            app.dphi0sEditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.psi0EditFieldLabel = uilabel(app.InitialconditionsPanel);
            app.psi0EditFieldLabel.Text = 'psi0 (°)';
            app.psi0EditField = uieditfield(app.InitialconditionsPanel, 'numeric');
            app.psi0EditField.Value = 180;
            app.TrajectoryparametersPanel = uipanel(app.UIFigure);
            app.TrajectoryparametersPanel.Title = 'Trajectory parameters';
            app.nEditFieldLabel = uilabel(app.TrajectoryparametersPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.TrajectoryparametersPanel, 'numeric');
            app.nEditField.Value = 25;
            app.widthmEditFieldLabel = uilabel(app.TrajectoryparametersPanel);
            app.widthmEditFieldLabel.Text = 'width (m)';
            app.widthmEditField = uieditfield(app.TrajectoryparametersPanel, 'numeric');
            app.widthmEditField.Value = 40;
            app.RotationEditFieldLabel = uilabel(app.TrajectoryparametersPanel);
            app.RotationEditFieldLabel.Text = 'Rotation (°)';
            app.RotationEditField = uieditfield(app.TrajectoryparametersPanel, 'numeric');
            app.ThetacenterLabel = uilabel(app.TrajectoryparametersPanel);
            app.ThetacenterLabel.Text = 'Élévation (°)';
            app.lvationEditField = uieditfield(app.TrajectoryparametersPanel, 'numeric');
            app.lvationEditField.Value = 55;
            app.AzimuthEditFieldLabel = uilabel(app.TrajectoryparametersPanel);
            app.AzimuthEditFieldLabel.Text = 'Azimuth (°)';
            app.AzimuthEditField = uieditfield(app.TrajectoryparametersPanel, 'numeric');
            app.SimulationparametersPanel = uipanel(app.UIFigure);
            app.SimulationparametersPanel.Title = 'Simulation parameters';
            app.RelativewindspeedktsEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.RelativewindspeedktsEditFieldLabel.Text = 'Relative wind speed (kts)';
            app.RelativewindspeedktsEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.RelativewindspeedktsEditField.Value = 15;
            app.SimulationlengthsEditFieldLabel = uilabel(app.SimulationparametersPanel);
            app.SimulationlengthsEditFieldLabel.Text = 'Simulation length (s)';
            app.SimulationlengthsEditField = uieditfield(app.SimulationparametersPanel, 'numeric');
            app.SimulationlengthsEditField.Value = 6.8;
            app.ControllerparametersPanel = uipanel(app.UIFigure);
            app.ControllerparametersPanel.Title = 'Controller parameters';
            app.ProportionalgainEditFieldLabel = uilabel(app.ControllerparametersPanel);
            app.ProportionalgainEditFieldLabel.Text = 'Proportional gain';
            app.ProportionalgainEditField = uieditfield(app.ControllerparametersPanel, 'numeric');
            app.ProportionalgainEditField.Value = 1;
            app.d0mEditFieldLabel = uilabel(app.ControllerparametersPanel);
            app.d0mEditFieldLabel.Text = 'd0 (m)';
            app.d0mEditField = uieditfield(app.ControllerparametersPanel, 'numeric');
            app.d0mEditField.Value = 10;
            app.ControlsignalsaturationEditFieldLabel = uilabel(app.ControllerparametersPanel);
            app.ControlsignalsaturationEditFieldLabel.Text = 'Control signal saturation';
            app.ControlsignalsaturationEditField = uieditfield(app.ControllerparametersPanel, 'numeric');
            app.ControlsignalsaturationEditField.Value = 0.5;
            app.ConstantControlInputEditFieldLabel = uilabel(app.ControllerparametersPanel);
            app.ConstantControlInputEditFieldLabel.Text = 'Constant control input';
            app.ConstantControlInput = uieditfield(app.ControllerparametersPanel, 'numeric');
            app.KitetrajectoryinflightwindowLabel = uilabel(app.UIFigure);
            app.KitetrajectoryinflightwindowLabel.Text = 'Kite trajectory in flight window';
            app.DifferentialcontrolsignalLabel = uilabel(app.UIFigure);
            app.DifferentialcontrolsignalLabel.Text = 'Differential control signal';
            app.AdditionalplotsCheckBox = uicheckbox(app.UIFigure);
            app.AdditionalplotsCheckBox.Text = 'Additional plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end